classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        UIAxes      matlab.ui.control.UIAxes
        rawButton   matlab.ui.control.Button
        Label_2     matlab.ui.control.Label
        Frame       matlab.ui.control.NumericEditField
        result      matlab.ui.control.UIAxes
        Label       matlab.ui.control.Label
        width       matlab.ui.control.NumericEditField
        height      matlab.ui.control.NumericEditField
        Label_3     matlab.ui.control.Label
        Frame_left  matlab.ui.control.NumericEditField
        result_2    matlab.ui.control.UIAxes
        result_3    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function result = binary_insertation(app,A)
            [height,width] = size(A);
            temp_A = zeros(height+2,width+2);
            temp_A(2:height+1,2:width+1) = A;
            A = temp_A;
            bias = 1;
            result = zeros(height,width,3);
            for i=1:2:height
                for j=1:2:width
                    
                    %[1,1]
                    %red channel
                    result(i,j,1)=0.5*(A(i+bias,j-1+bias)+A(i+bias,j+1+bias));
                    
                    %green channel
                    result(i,j,2)=A(i+bias,j+bias);
                    
                    %blue channel
                    result(i,j,3)=0.5*(A(i-1+bias,j+bias)+A(i+1+bias,j+bias));
                    
                    j = j+1;
                    %[1,2]
                    %red channel
                    result(i,j,1)=A(i+bias,j+bias);
                    
                    %green channel
                    result(i,j,2)=0.25*(A(i-1+bias,j+bias)+A(i+bias,j-1+bias)+A(i+bias,j+1+bias)+A(i+1+bias,j+bias));
                    
                    %blue channel
                    result(i,j,3)=0.25*(A(i-1+bias,j-1+bias)+A(i-1+bias,j+1+bias)+A(i+1+bias,j-1+bias)+A(i+1+bias,j+1+bias));
                    
                    j = j-1;
                    i = i+1; 
                    %[2,1]
                    %red channel
                    result(i,j,1)=0.25*(A(i-1+bias,j-1+bias)+A(i-1+bias,j+1+bias)+A(i+1+bias,j-1+bias)+A(i+1+bias,j+1+bias));
                    
                    %green channel
                    result(i,j,2)=0.25*(A(i-1+bias,j+bias)+A(i+bias,j-1+bias)+A(i+bias,j+1+bias)+A(i+1+bias,j+bias));
                    
                    %blue channel
                    result(i,j,3)=A(i+bias,j+bias);
                    
                    j = j+1;
                    %[2,2]
                    %red channel
                    result(i,j,1)=0.5*(A(i-1+bias,j+bias)+A(i+1+bias,j+bias));

                    %green channel
                    result(i,j,2)=A(i+bias,j+bias);
                    
                     %blue channel
                    result(i,j,3)=0.5*(A(i+bias,j-1+bias)+A(i+bias,j+1+bias));
                    
                    i = i-1;
                    j = j-1;
                end
            end
            result = uint8(result);
        end


        function w = weight(app,distance)
            global a;
            x = distance/4;
            w = 1-(a+3)*x*x+(a+2)*x*x;
        end
    
        function [r,g,b] = convolution(app, bias, x,y,A,position)
            r = 0;
            g = 0;
            b = 0;
            if position==1
                unitization = 2*weight(app,1)+2*weight(app,1)*weight(app,2);
                r = (weight(app,1)/unitization)*(A(x+bias,y-1+bias)+A(x+bias,y+1+bias))+(weight(app,1)*weight(app,2)/unitization)*(A(x+2+bias,y-1+bias)+A(x+2+bias,y+1+bias));
                unitization_green = 1+4*weight(app,1)*weight(app,1)+2*weight(app,2)+weight(app,2)*weight(app,2);
                
                g = (1/unitization_green)*A(x+bias,y+bias)+(weight(app,1)*weight(app,1)/unitization_green)*(A(x-1+bias,y-1+bias)+A(x-1+bias,y+1+bias)+A(x+1+bias,y-1+bias)+A(x+1+bias,y+1+bias))+(weight(app,2)/unitization_green)*(A(x+2+bias,y+bias)+A(x+bias,y+2+bias))+(weight(app,2)*weight(app,2)/unitization_green)*A(x+2+bias,y+2+bias);
                b = (weight(app,1)/unitization)*(A(x+1+bias,y+bias)+A(x-1+bias,y+bias))+(weight(app,1)*weight(app,2)/unitization)*(A(x-1+bias,y+2+bias)+A(x+1+bias,y+2+bias));
            end
            if position==2
                unitization = 4*weight(app,1)+4*weight(app,1)*weight(app,2); 
                unitization_11 = 4*weight(app,1)*weight(app,1);
                unitization_self = 1+2*weight(app,2)+weight(app,2)*weight(app,2);
                r=(1/unitization_self)*A(x+bias,y+bias)+(weight(app,2)/unitization_self)*(A(x+2+bias,y+bias)+A(x+bias,y+2+bias))+(weight(app,2)*weight(app,2)/unitization_self)*A(x+2+bias,y+2+bias);
                
                g=(weight(app,1)/unitization)*(A(x-1+bias,y+bias)+A(x+1+bias,y+bias)+A(x+bias,y-1+bias)+A(x+bias,y+1+bias))+(weight(app,1)*weight(app,2)/unitization)*(A(x+2+bias,y-1+bias)+A(x+2+bias,y+1+bias)+A(x-1+bias,y+2+bias)+A(x+1+bias,y+2+bias));
                b=(weight(app,1)*weight(app,1)/unitization_11)*(A(x-1+bias,y-1+bias)+A(x+1+bias,y+1+bias)+A(x+1+bias,y-1+bias)+A(x-1+bias,y+1+bias));
            end
            if position==3
                unitization_11 = 4*weight(app,1)*weight(app,1);
                unitization = 4*weight(app,1)+4*weight(app,1)*weight(app,2); 
                unitization_self = 1+2*weight(app,2)+weight(app,2)*weight(app,2);
                r=(weight(app,1)*weight(app,1)/unitization_11)*(A(x-1+bias,y-1+bias)+A(x+1+bias,y+1+bias)+A(x+1+bias,y-1+bias)+A(x-1+bias,y+1+bias));
                
                g=(weight(app,1)/unitization)*(A(x-1+bias,y+bias)+A(x+1+bias,y+bias)+A(x+bias,y-1+bias)+A(x+bias,y+1+bias))+(weight(app,1)*weight(app,2)/unitization)*(A(x+2+bias,y-1+bias)+A(x+2+bias,y+1+bias)+A(x-1+bias,y+2+bias)+A(x+1+bias,y+2+bias));

                b=(1/unitization_self)*A(x+bias,y+bias)+(weight(app,2)/unitization_self)*(A(x+2+bias,y+bias)+A(x+bias,y+2+bias))+(weight(app,2)*weight(app,2)/unitization_self)*A(x+2+bias,y+2+bias);
            end
            if position==4
                unitization = 2*weight(app,1)+2*weight(app,1)*weight(app,2); 
                r=(weight(app,1)/unitization)*(A(x+1+bias,y+bias)+A(x-1+bias,y+bias))+(weight(app,1)*weight(app,2)/unitization)*(A(x-1+bias,y+2+bias)+A(x+1+bias,y+2+bias));
                unitization_self = 1+4*weight(app,1)*weight(app,1)+2*weight(app,2)+weight(app,2)*weight(app,2);
                g=(1/unitization_self)*A(x+bias,y+bias)+(weight(app,1)*weight(app,1)/unitization_self)*(A(x-1+bias,y-1+bias)+A(x-1+bias,y+1+bias)+A(x+1+bias,y-1+bias)+A(x+1+bias,y+1+bias))+(weight(app,2)/unitization_self)*(A(x+2+bias,y+bias)+A(x+bias,y+2+bias))+(weight(app,2)*weight(app,2)/unitization_self)*A(x+2+bias,y+2+bias);
                b=(weight(app,1)/unitization)*(A(x+bias,y+1+bias)+A(x+bias,y-1+bias))+(weight(app,2)*weight(app,1)/unitization)*(A(x+2+bias,y-1+bias)+A(x+2+bias,y+1+bias));
            end
        end
        function result = bicubic_interpolation(app,A)
            [height,width] = size(A);
            temp_A = zeros(height+4,width+4);
            temp_A(3:height+2,3:width+2) = A;
            A = temp_A;
            bias = 2;
            result = zeros(height,width,3);
            for i=1:2:height
                for j=1:2:width
                    %[1,1]
                    [result(i,j,1),result(i,j,2),result(i,j,3)]=convolution(app,bias,i,j,A,1);
                    %[1,2] 
                    j = j+1;
                    [result(i,j,1),result(i,j,2),result(i,j,3)]=convolution(app,bias,i,j,A,2);
                    
                    %[2,1]
                    j = j-1;
                    i = i+1; 
                    [result(i,j,1),result(i,j,2),result(i,j,3)]=convolution(app,bias,i,j,A,3);
                    
                    %[2,2]
                    j = j+1;
                    [result(i,j,1),result(i,j,2),result(i,j,3)]=convolution(app,bias,i,j,A,4);
                    
                    i = i-1;
                    j = j-1;
                end
            end
            result = uint8(result);
        end
        
        function result = nearest_method(app,A)
            [height,width] = size(A);
            temp_A = zeros(height+2,width+2);
            temp_A(2:height+1,2:width+1) = A;
            A = temp_A;
            bias = 1;
            result = zeros(height,width,3);
            for i=1:2:height
                for j=1:2:width
                    result(i,j,1) = A(i+bias,j+bias+1);
                    result(i,j,2) = A(i+1+bias,j+bias);
                    result(i,j,3) = A(i+bias,j+bias);
                    
                    j = j+1;
                    result(i,j,1) = A(i+bias,j+bias);
                    result(i,j,2) = A(i+bias,j+1+bias);
                    result(i,j,3) = A(i+1+bias,j+1+bias);
                    
                    j = j-1;
                    i = i+1;
                    result(i,j,1) = A(i+1+bias,j+1+bias);
                    result(i,j,2) = A(i+bias,j+1+bias);
                    result(i,j,3) = A(i+bias,j+bias);
                    
                    j = j+1;
                    result(i,j,1) = A(i+1+bias,j+bias);
                    result(i,j,2) = A(i+bias,j+bias);
                    result(i,j,3) = A(i+bias,j+1+bias);
                    i = i-1;
                    j = j-1;
                end
            end
            result = uint8(result);
        end
    end
    

    methods (Access = private)

        % Button pushed function: rawButton
        function rawButtonPushed(app, event)
            global img;
            [filename,pathname] = uigetfile({'.raw'},'选择raw文件');
            if (isequal(filename,0)||isequal(pathname,0)) 
                errordlg('未选择文件','错误');
            else 
                img = fopen([pathname,filename],'r');
                row = app.width.Value;
                col = app.height.Value; 
                frames = app.Frame.Value;
                app.Frame_left.Value = app.Frame.Value;
                for i = 1:frames
                    Y = (fread(img,[row,col]))';
                    imshow(Y,[],'Parent',app.UIAxes);
                    bin = binary_insertation(app,Y);
                    imshow(bin,'Parent',app.result);
                    global a;
                    a = -0.5;
                    bic = bicubic_interpolation(app,Y);
                    imshow(bic,'Parent',app.result_2);
                    
                    nearest = nearest_method(app,Y);
                    imshow(nearest,'Parent',app.result_3);
                    pause(0.002)
                    app.Frame_left.Value = app.Frame_left.Value-1;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图像')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.rawButton = uibutton(app.UIFigure, 'push');
            app.rawButton.ButtonPushedFcn = createCallbackFcn(app, @rawButtonPushed, true);
            app.rawButton.Text = '选择.raw文件';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '输入总帧数  ';
            app.Frame = uieditfield(app.UIFigure, 'numeric');
            app.Frame.Value = 90;
            app.result = uiaxes(app.UIFigure);
            title(app.result, '双线性插值处理结果')
            xlabel(app.result, '')
            ylabel(app.result, '')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '输入图像尺寸';
            app.width = uieditfield(app.UIFigure, 'numeric');
            app.width.Value = 352;
            app.height = uieditfield(app.UIFigure, 'numeric');
            app.height.Value = 288;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '剩余帧数   ';
            app.Frame_left = uieditfield(app.UIFigure, 'numeric');
            app.result_2 = uiaxes(app.UIFigure);
            title(app.result_2, '双三次插值')
            xlabel(app.result_2, '')
            ylabel(app.result_2, '')
            app.result_3 = uiaxes(app.UIFigure);
            title(app.result_3, '临近算法')
            xlabel(app.result_3, '')
            ylabel(app.result_3, '')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end